classdef rotation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CELL_ID                    matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        SettingsPanel              matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        verticaloffsetSlider       matlab.ui.control.Slider
        verticaloffsetSliderLabel  matlab.ui.control.Label
        strokesizeSlider           matlab.ui.control.Slider
        strokesizeSliderLabel      matlab.ui.control.Label
        fontsizeSlider             matlab.ui.control.Slider
        fontsizeSliderLabel        matlab.ui.control.Label
        Panel_3                    matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        ResetSettingsButton        matlab.ui.control.Button
        BreakpointButton           matlab.ui.control.Button
        RuntestroutineButton       matlab.ui.control.Button
        ResetGUIButton             matlab.ui.control.Button
        DrawButton                 matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mouse = struct( ...
            'state', {0}, ...
            'pos', {[0 0]}, ...
            'drag', {[]});
        
        rotation_stack = struct( ...
            'roi', {[]}, ...
            'gui', {{}}, ...
            'listeners', {{}}, ...
            'cache', {0});

        VolumeDropDown = struct('Value', {'Colormap'});
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.rotation_stack.cache = struct('Colormap', {{}}, 'Video', {{}});
            
            test_img = load('test_file.mat');
            test_data = test_img.data;
            test_view = squeeze(test_data(:, :, 15, 1:3)*1100);
            image(app.UIAxes, test_view);
            daspect(app.UIAxes, [1 1 1]);
            app.UIAxes.XLim(2) = size(test_view, 2);
            app.UIAxes.YLim(2) = size(test_view, 1);
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            roi = drawrectangle(app.UIAxes,'Color','black','StripeColor','m');
            Program.rotation_gui.draw(app, roi);
        end

        % Button pushed function: RuntestroutineButton
        function RuntestroutineButtonPushed(app, event)
            roi = drawrectangle(app.UIAxes,'Color','black','StripeColor','m');
            Program.rotation_gui.draw(app, roi);
            rotation_stack = app.rotation_stack;

            % Take screen capture
            robot = java.awt.Robot();
            pos = app.CELL_ID.Position;
            rect = java.awt.Rectangle(pos(1),pos(2),pos(3),pos(4));
            cap = robot.createScreenCapture(rect);

            % Convert to an RGB image
            rgb = typecast(cap.getRGB(0,0,cap.getWidth,cap.getHeight,[],0,cap.getWidth),'uint8');
            imgData = zeros(cap.getHeight,cap.getWidth,3,'uint8');
            imgData(:,:,1) = reshape(rgb(3:4:end),cap.getWidth,[])';
            imgData(:,:,2) = reshape(rgb(2:4:end),cap.getWidth,[])';
            imgData(:,:,3) = reshape(rgb(1:4:end),cap.getWidth,[])';

            rotation_stack.screen_cap = {imgData};
            
            save('rotation_debug.mat', '-struct', 'rotation_stack');
        end

        % Button pushed function: BreakpointButton
        function BreakpointButtonPushed(app, event)
            keyboard;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CELL_ID = uifigure('Visible', 'off');
            app.CELL_ID.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.CELL_ID);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.DrawButton = uibutton(app.GridLayout2, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.ResetGUIButton = uibutton(app.GridLayout2, 'push');
            app.ResetGUIButton.Text = 'Reset GUI';
            app.RuntestroutineButton = uibutton(app.GridLayout2, 'push');
            app.RuntestroutineButton.ButtonPushedFcn = createCallbackFcn(app, @RuntestroutineButtonPushed, true);
            app.RuntestroutineButton.Text = 'Run test routine';
            app.BreakpointButton = uibutton(app.GridLayout2, 'push');
            app.BreakpointButton.ButtonPushedFcn = createCallbackFcn(app, @BreakpointButtonPushed, true);
            app.BreakpointButton.Text = 'Breakpoint';
            app.ResetSettingsButton = uibutton(app.GridLayout2, 'push');
            app.ResetSettingsButton.Text = 'Reset Settings';
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayout3 = uigridlayout(app.SettingsPanel);
            app.fontsizeSliderLabel = uilabel(app.GridLayout3);
            app.fontsizeSliderLabel.Text = 'font size';
            app.fontsizeSlider = uislider(app.GridLayout3);
            app.fontsizeSlider.Value = 20;
            app.strokesizeSliderLabel = uilabel(app.GridLayout3);
            app.strokesizeSliderLabel.Text = 'stroke size';
            app.strokesizeSlider = uislider(app.GridLayout3);
            app.strokesizeSlider.Value = 12;
            app.verticaloffsetSliderLabel = uilabel(app.GridLayout3);
            app.verticaloffsetSliderLabel.Text = 'vertical offset';
            app.verticaloffsetSlider = uislider(app.GridLayout3);
            app.verticaloffsetSlider.Value = 20;
            % Show the figure after all components are created
            app.CELL_ID.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rotation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CELL_ID)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CELL_ID)
        end
    end
end