classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GetnodecolorButton  matlab.ui.control.Button
        AddnodeButton       matlab.ui.control.Button
        GetnodeinfoButton   matlab.ui.control.Button
        ColornodesButton    matlab.ui.control.Button
        Tree                matlab.ui.container.CheckBoxTree
        IDdNode             matlab.ui.container.TreeNode
        VA11Node            matlab.ui.container.TreeNode
        VB3Node             matlab.ui.container.TreeNode
        DD5Node             matlab.ui.container.TreeNode
        UnIDdNode           matlab.ui.container.TreeNode
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ColornodesButton
        function ColornodesButtonPushed(app, event)
            get(app.VB3Node)
            redStyle = uistyle("FontColor",[1,0,0]);
            addStyle(app.Tree,redStyle,"node",app.VB3Node)
            blueStyle = uistyle("FontColor",[0,0,1]);
            addStyle(app.Tree,blueStyle,"node",app.VA11Node)
            greenStyle = uistyle("FontColor",[0,1,0]);
            addStyle(app.Tree,greenStyle,"node",app.DD5Node)
        end

        % Button pushed function: GetnodeinfoButton
        function GetnodeinfoButtonPushed(app, event)
            target_worldline = app.Tree.SelectedNodes.Text;

            %check = uiconfirm(app.CELL_ID, sprintf('Are you sure you want to delete Worldline ID #%.f (%s)?', , target_worldline), 'NeuroPAL_ID','Options',['Yes','No']);
        end

        % Button pushed function: AddnodeButton
        function AddnodeButtonPushed(app, event)
            new_worldline = uitreenode(app.UnIDdNode, "Text", string(size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1)+1));
            newStyle = uistyle("FontColor",[0.4,0.4,0.4]);
            addStyle(app.Tree,newStyle,"node",new_worldline);
            new_worldline.NodeData = size(app.IDdNode.Children,1)+size(app.UnIDdNode.Children,1);
            new_worldline
        end

        % Button pushed function: GetnodecolorButton
        function GetnodecolorButtonPushed(app, event)
            get(app.Tree.SelectedNodes)
            get(app.Tree.SelectedNodes.Parent)
            get(app.Tree.SelectedNodes.Parent.Parent)
            app.Tree.SelectedNodes.Parent.Parent.StyleConfigurations
            app.Tree.SelectedNodes.NodeData
            app.Tree.SelectedNodes.Parent.Parent.StyleConfigurations(app.Tree.SelectedNodes.NodeData,:)
            style = app.Tree.SelectedNodes.Parent.Parent.StyleConfigurations(app.Tree.SelectedNodes.NodeData,:);
            style.Style
            style.Style.FontColor
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.IDdNode = uitreenode(app.Tree);
            app.IDdNode.Text = 'ID''d';
            app.VA11Node = uitreenode(app.IDdNode);
            app.VA11Node.NodeData = 1;
            app.VA11Node.Text = 'VA11';
            app.VB3Node = uitreenode(app.IDdNode);
            app.VB3Node.NodeData = 2;
            app.VB3Node.Text = 'VB3';
            app.DD5Node = uitreenode(app.IDdNode);
            app.DD5Node.NodeData = 3;
            app.DD5Node.Text = 'DD5';
            app.UnIDdNode = uitreenode(app.Tree);
            app.UnIDdNode.Text = 'Un-ID''d';
            app.ColornodesButton = uibutton(app.UIFigure, 'push');
            app.ColornodesButton.ButtonPushedFcn = createCallbackFcn(app, @ColornodesButtonPushed, true);
            app.ColornodesButton.Text = 'Color nodes';
            app.GetnodeinfoButton = uibutton(app.UIFigure, 'push');
            app.GetnodeinfoButton.ButtonPushedFcn = createCallbackFcn(app, @GetnodeinfoButtonPushed, true);
            app.GetnodeinfoButton.Text = 'Get node info';
            app.AddnodeButton = uibutton(app.UIFigure, 'push');
            app.AddnodeButton.ButtonPushedFcn = createCallbackFcn(app, @AddnodeButtonPushed, true);
            app.AddnodeButton.Text = 'Add node #';
            app.GetnodecolorButton = uibutton(app.UIFigure, 'push');
            app.GetnodecolorButton.ButtonPushedFcn = createCallbackFcn(app, @GetnodecolorButtonPushed, true);
            app.GetnodecolorButton.Text = 'Get node color';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end