classdef npal_comp_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout8                   matlab.ui.container.GridLayout
        Panel_3                       matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        Step7UploadDistributePanel    matlab.ui.container.Panel
        Step6CodesignNotarizeMacOSonlyPanel  matlab.ui.container.Panel
        GridLayout9                   matlab.ui.container.GridLayout
        Button4                       matlab.ui.control.Button
        Label3                        matlab.ui.control.Label
        Label2                        matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Button3                       matlab.ui.control.Button
        Button2                       matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        Step5CompileNeuroPAL_IDPanel  matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        Panel_5                       matlab.ui.container.Panel
        GridLayout11                  matlab.ui.container.GridLayout
        ForceenablecompilationstartCheckBox  matlab.ui.control.CheckBox
        CompileNeuroPAL_IDButton      matlab.ui.control.Button
        Panel_4                       matlab.ui.container.Panel
        GridLayout12                  matlab.ui.container.GridLayout
        MatlabRuntimeLamp_2           matlab.ui.control.Lamp
        MatlabRuntimeLamp_2Label      matlab.ui.control.Label
        DependenciesLamp              matlab.ui.control.Lamp
        DependenciesLampLabel         matlab.ui.control.Label
        NamespacesLamp                matlab.ui.control.Lamp
        NamespacesLampLabel           matlab.ui.control.Label
        ModelsLamp                    matlab.ui.control.Lamp
        ModelsLampLabel               matlab.ui.control.Label
        BinariesLamp                  matlab.ui.control.Lamp
        BinariesLampLabel             matlab.ui.control.Label
        visualize_lightLamp           matlab.ui.control.Lamp
        visualize_lightLampLabel      matlab.ui.control.Label
        Step1DefinedirectoriesPanel   matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        SelectFolderButton_Devtools   matlab.ui.control.Button
        DevtoolsPathLabel             matlab.ui.control.Label
        NeuroPAL_IDPathLabel          matlab.ui.control.Label
        SelectFolderButton_NPAL       matlab.ui.control.Button
        Step2VerifysystemconfigurationPanel  matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        WarningLabel                  matlab.ui.control.Label
        MLComputeButton               matlab.ui.control.Button
        PyComputeButton               matlab.ui.control.Button
        PythonEnvSizeField            matlab.ui.control.EditField
        MatlabEnvSizeField            matlab.ui.control.EditField
        ApproxSizeLabel               matlab.ui.control.Label
        MatlabReqField                matlab.ui.control.EditField
        PythonReqField                matlab.ui.control.EditField
        RequiredLabel                 matlab.ui.control.Label
        SystemLabel                   matlab.ui.control.Label
        PythonversionEditField        matlab.ui.control.EditField
        PythonversionEditFieldLabel   matlab.ui.control.Label
        MATLABRuntimeEditField        matlab.ui.control.EditField
        MATLABRuntimeEditFieldLabel   matlab.ui.control.Label
        Step4CompilepythonfilesPanel  matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        Panel                         matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        Panel_2                       matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        ScriptTree                    matlab.ui.container.CheckBoxTree
        ValidationDropDown            matlab.ui.control.DropDown
        SelectscriptsCheckBox         matlab.ui.control.CheckBox
        ValidatefilesCheckBox         matlab.ui.control.CheckBox
        DistributionFolderLabel       matlab.ui.control.Label
        SelectFolderButton_Dist       matlab.ui.control.Button
        SelectFolderButton_Script     matlab.ui.control.Button
        ScriptFolderLabel             matlab.ui.control.Label
        CompilepythonfilesButton      matlab.ui.control.Button
        Step3VerifydependenciesPanel  matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        DependencyTree                matlab.ui.container.CheckBoxTree
        MATLABNode                    matlab.ui.container.TreeNode
        PythonNode                    matlab.ui.container.TreeNode
        ConsoleTextArea               matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        ml_env; % stores ver call
        py_env; % stores pyenv call

        npal_path;
        devtool_path;
        script_path;
        dist_path;

        os;
    end
    
    methods (Access = public)

        function write_log(app, msg)
            app.ConsoleTextArea.Value{end+1} = sprintf('\n%s', msg);
            scroll(app.ConsoleTextArea, 'bottom')
        end
        
        function total_bytes = get_dir_size(app, path, fmts, progress)
            progress_msg = progress.Message;
            total_bytes = 0;

            progress.Message = "Searching environment...";
            S = dir(path);

            progress.Message = "Grabbing subdirectories...";
            N = setdiff({S([S.isdir]).name},{'.','..'});

            for ii = 1:numel(N)
                p_msg = char(sprintf("%s \nScanning %s...", progress_msg, N{ii}));
                progress.Message = p_msg;
                progress.Value = ii/numel(N);
                app.write_log(p_msg);

                T = dir(fullfile(path,N{ii},fmts));
                C = {T(~[T.isdir]).name};

                for jj = 1:numel(C)
                    progress.Message = sprintf("%s -> %s...", p_msg(1:end-3), C{jj});
                    progress.Value = min(ii+(jj/numel(C)), 1)/numel(N);
                    app.write_log(progress.Message);

                    F = dir(fullfile(path,N{ii},C{jj}));
                    if size(F, 1) == 1
                        total_bytes = total_bytes + F.bytes;
                    else
                        total_bytes = total_bytes + sum([F.bytes]);
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.write_log("Grabbing matlab environment...");
            app.ml_env = ver;
            app.MATLABRuntimeEditField.Value = app.ml_env.Release;
            app.MATLABRuntimeEditField.Value = app.MATLABRuntimeEditField.Value(2:end-1);

            app.write_log("Grabbing python environment...");
            app.py_env = pyenv;
            app.PythonversionEditField.Value = app.py_env.Version;

            if ispc
                app.os = struct('short', {'win'}, 'long', {'windows'});
            elseif ismac
                app.os = struct('short', {'mac'}, 'long', {'macos'});
            end

            CheckedNodes = [];
            app.write_log("Reading matlab toolboxes...");
            for tb=1:length(app.ml_env)
               toolbox_string = sprintf("%s v%s", app.ml_env(tb).Name, app.ml_env(tb).Version);
               tb_node = uitreenode("Parent", app.MATLABNode, "Text", toolbox_string);
               CheckedNodes = [CheckedNodes tb_node];
            end

            [status, pip_list] = system("pip list");
            app.write_log("Reading pip list...");
            if ~status
               app.write_log(pip_list);
               pip_list = splitlines(pip_list);
               pip_list{1}=[];pip_list{2}=[];
               for lib=1:length(pip_list)
                   library = pip_list{lib};
                   if ~isempty(library) && ~contains(library, 'notice')
                       library_info = split(library, ' ');
                       library_string = sprintf("%s v%s", library_info{1}, library_info{end});
                       lib_node = uitreenode("Parent", app.PythonNode, "Text", library_string);
                       CheckedNodes = [CheckedNodes lib_node];
                   end
               end
            else
               app.write_log("pip list call failed --> There may be an issue with your python environment.");
               error("pip list call failed --> There may be an issue with your python environment.");
            end

           app.DependencyTree.CheckedNodes = CheckedNodes;
        end

        % Button pushed function: PyComputeButton
        function PyComputeButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, "Message", "Calculating pyenv size...", "Indeterminate", "off");
            sp_folder = fullfile(app.py_env.Home, 'Lib', 'site-packages', '*');
            g_bytes = app.get_dir_size(sp_folder, '*.py', d)/1e9;

            if g_bytes < 0.1
                app.PythonEnvSizeField.Value = "<0.1 gb";
            else
                app.PythonEnvSizeField.Value = sprintf("%.2f gb", num2str(g_bytes));
            end

            if g_bytes > 1
                num_py_files = length(dir(fullfile(app.npal_dir,'+Wrapper', '**','*.py')));
                app.WarningLabel.Text = sprintf(app.WarningLabel.Text, g_bytes, g_bytes*num_py_files);
                app.GridLayout3.RowHeight = {'fit', 'fit', 'fit', 25};
            end

            app.PyComputeButton.Visible = 'off';
            app.PythonEnvSizeField.Visible = 'on';
            close(d)
        end

        % Button pushed function: MLComputeButton
        function MLComputeButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, "Message", "Calculating MATLAB environment size...", "Indeterminate", "off");
            tb_folder = fullfile(getenv('APPDATA'), 'Mathworks', 'MATLAB Add-Ons', 'Toolboxes');
            g_bytes = app.get_dir_size(tb_folder, '*', d)/1e9;

            if g_bytes < 0.1
                app.MatlabEnvSizeField.Value = "<0.1 gb";
            else
                app.MatlabEnvSizeField.Value = sprintf("%.2f gb", num2str(total_bytes));
            end

            app.MLComputeButton.Visible = 'off';
            app.MatlabEnvSizeField.Visible = 'on';
            close(d)
        end

        % Button pushed function: SelectFolderButton_NPAL
        function SelectFolderButton_NPALPushed(app, event)
            default_path = fullfile('C:', 'Users', getenv('username'), 'Documents', 'GitHub', 'NeuroPAL_ID');
            app.npal_path = uigetdir(default_path, "Select NeuroPAL_ID path.");
            if ~isempty(app.npal_path)
                app.SelectFolderButton_NPAL.Text = app.npal_path;
                
                if isfile(fullfile(app.npal_path, 'visualize_light.mlapp'))
                    validation_color = [0.52, 1, 0.52];
                    app.visualize_lightLamp.Color = [0, 1, 0];
                else
                    validation_color = [1, 1, 0.52];
                    app.write_log("WARNING: visualize_light.mlapp not found in selected NeuroPAL_ID path!");
                end

                app.SelectFolderButton_NPAL.BackgroundColor = validation_color;

                app.script_path = fullfile(app.npal_path, '+Wrapper');
                app.SelectFolderButton_Script.Text = app.script_path;

                if isfile(fullfile(app.script_path, 'recommend_frames.py'))
                    validation_color = [0.52, 1, 0.52];
                else
                    validation_color = [1, 1, 0.52];
                    app.write_log("WARNING: recommend_frames.py not found in selected script path!");
                end
                app.SelectFolderButton_Script.BackgroundColor = validation_color;


                if ~isempty(app.script_path)
                    py_files = dir(app.script_path);
                    app.write_log("Scanning script path...");
                    for script=1:length(py_files)
                        py_file = fullfile(py_files(script).folder, py_files(script).name);
                        if isfile(py_file) && endsWith(py_file, '.py')
                            app.write_log(sprintf("Found %s", py_file));
                            uitreenode("Text", py_files(script).name, "Parent", app.ScriptTree);
                        end
                    end

                    app.ScriptTree.CheckedNodes = [];
                end

                app.dist_path = fullfile(app.npal_path, sprintf('%s_visualize', app.os.short), 'for_redistribution_files_only', 'lib', 'bin', app.os.long);
                app.SelectFolderButton_Dist.Text = app.dist_path;
            end
        end

        % Button pushed function: SelectFolderButton_Devtools
        function SelectFolderButton_DevtoolsPushed(app, event)
            default_path = fullfile('C:', 'Users', getenv('username'), 'Documents', 'GitHub', 'misc_tools');
            app.devtool_path = uigetdir(default_path, "Select misc_tools path.");
            if ~isempty(app.devtool_path)
                app.SelectFolderButton_Devtools.Text = app.devtool_path;

                if isfolder(fullfile(app.devtool_path, 'utilities'))
                    validation_color = [0.52, 1, 0.52];
                else
                    validation_color = [1, 1, 0.52];
                    app.write_log("WARNING: utilities folder not found in selected dev tools path!");
                end

                app.SelectFolderButton_Devtools.BackgroundColor = validation_color;
            end
        end

        % Value changed function: ValidatefilesCheckBox
        function ValidatefilesCheckBoxValueChanged(app, event)
            if app.ValidatefilesCheckBox.Value
                app.ValidationDropDown.Enable = "on";
            else
                app.ValidationDropDown.Enable = "off";
            end
        end

        % Value changed function: SelectscriptsCheckBox
        function SelectscriptsCheckBoxValueChanged(app, event)
            if app.SelectscriptsCheckBox.Value
                app.ScriptTree.Enable = "on";
            else
                app.ScriptTree.Enable = "off";
            end
        end

        % Button pushed function: CompilepythonfilesButton
        function CompilepythonfilesButtonPushed(app, event)
            cmd = string(sprintf('python "%s" --input_path="%s" --output_path="%s"', fullfile(app.devtool_path, 'utilities', 'script_compiler.py'), app.script_path, app.dist_path));

            if app.ValidatefilesCheckBox.Value
                cmd = cmd.append(" --validate_files=True --on_fail=%s", app.ValidationDropDown.Value.strrep('On fail, ', ''));
            end

            if app.SelectscriptsCheckBox.Value
                cmd = cmd.append(" --select_scripts=");
                script_nodes = app.ScriptTree.CheckedNodes;
                for script=1:length(script_nodes)
                    target_script = script_nodes(script);

                    if script ~= length(script_nodes)
                        cmd = cmd.append(sprintf("%s,", target_script.Text));
                    else
                        cmd = cmd.append(sprintf("%s", target_script.Text));
                    end
                end

                if endsWith(cmd, ',')
                    cmd = cmd(1:end-1);
                end
            end

            app.write_log(sprintf("Issuing system call:\n%s", cmd));

            d = uiprogressdlg(app.UIFigure, "Message", "Compiling python scripts...", "Indeterminate", "on");
            [state, output] = system(cmd);
            app.write_log(output)
            close(d);

            if ~state
                app.CompilepythonfilesButton.BackgroundColor = [0.52, 1, 0.52];
            else
                app.write_log("WARNING: Compilation of python files failed!");
                error("WARNING: Compilation of python files failed!");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout8 = uigridlayout(app.UIFigure);
            app.ConsoleTextArea = uitextarea(app.GridLayout8);
            app.Panel_3 = uipanel(app.GridLayout8);
            app.GridLayout = uigridlayout(app.Panel_3);
            app.Step3VerifydependenciesPanel = uipanel(app.GridLayout);
            app.Step3VerifydependenciesPanel.Title = 'Step 3: Verify dependencies';
            app.GridLayout4 = uigridlayout(app.Step3VerifydependenciesPanel);
            app.DependencyTree = uitree(app.GridLayout4, 'checkbox');
            app.MATLABNode = uitreenode(app.DependencyTree);
            app.MATLABNode.Text = 'MATLAB';
            app.PythonNode = uitreenode(app.DependencyTree);
            app.PythonNode.Text = 'Python';
            app.Step4CompilepythonfilesPanel = uipanel(app.GridLayout);
            app.Step4CompilepythonfilesPanel.Title = 'Step 4: Compile python files.';
            app.GridLayout6 = uigridlayout(app.Step4CompilepythonfilesPanel);
            app.Panel = uipanel(app.GridLayout6);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.CompilepythonfilesButton = uibutton(app.GridLayout5, 'push');
            app.CompilepythonfilesButton.ButtonPushedFcn = createCallbackFcn(app, @CompilepythonfilesButtonPushed, true);
            app.CompilepythonfilesButton.Text = 'Compile python files';
            app.ScriptFolderLabel = uilabel(app.GridLayout5);
            app.ScriptFolderLabel.Text = 'Script Folder:';
            app.SelectFolderButton_Script = uibutton(app.GridLayout5, 'push');
            app.SelectFolderButton_Script.Text = 'Select folder';
            app.SelectFolderButton_Dist = uibutton(app.GridLayout5, 'push');
            app.SelectFolderButton_Dist.Text = 'Select folder';
            app.DistributionFolderLabel = uilabel(app.GridLayout5);
            app.DistributionFolderLabel.Text = 'Distribution Folder:';
            app.Panel_2 = uipanel(app.GridLayout5);
            app.GridLayout7 = uigridlayout(app.Panel_2);
            app.ValidatefilesCheckBox = uicheckbox(app.GridLayout7);
            app.ValidatefilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValidatefilesCheckBoxValueChanged, true);
            app.ValidatefilesCheckBox.Text = 'Validate files.';
            app.SelectscriptsCheckBox = uicheckbox(app.GridLayout7);
            app.SelectscriptsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectscriptsCheckBoxValueChanged, true);
            app.SelectscriptsCheckBox.Text = 'Select scripts.';
            app.ValidationDropDown = uidropdown(app.GridLayout7);
            app.ValidationDropDown.Items = {'On fail, skipmove', 'On fail, deletefile', 'On fail, raiseerror'};
            app.ValidationDropDown.Enable = 'off';
            app.ValidationDropDown.Placeholder = 'On fail...';
            app.ValidationDropDown.Value = 'On fail, skipmove';
            app.ScriptTree = uitree(app.GridLayout7, 'checkbox');
            app.ScriptTree.Enable = 'off';
            app.Step2VerifysystemconfigurationPanel = uipanel(app.GridLayout);
            app.Step2VerifysystemconfigurationPanel.Title = 'Step 2: Verify system configuration';
            app.GridLayout3 = uigridlayout(app.Step2VerifysystemconfigurationPanel);
            app.MATLABRuntimeEditFieldLabel = uilabel(app.GridLayout3);
            app.MATLABRuntimeEditFieldLabel.Text = 'MATLAB  Runtime:';
            app.MATLABRuntimeEditField = uieditfield(app.GridLayout3, 'text');
            app.PythonversionEditFieldLabel = uilabel(app.GridLayout3);
            app.PythonversionEditFieldLabel.Text = 'Python version:';
            app.PythonversionEditField = uieditfield(app.GridLayout3, 'text');
            app.SystemLabel = uilabel(app.GridLayout3);
            app.SystemLabel.Text = 'System';
            app.RequiredLabel = uilabel(app.GridLayout3);
            app.RequiredLabel.Text = 'Required';
            app.PythonReqField = uieditfield(app.GridLayout3, 'text');
            app.MatlabReqField = uieditfield(app.GridLayout3, 'text');
            app.ApproxSizeLabel = uilabel(app.GridLayout3);
            app.ApproxSizeLabel.Text = 'Approx. Size';
            app.MatlabEnvSizeField = uieditfield(app.GridLayout3, 'text');
            app.MatlabEnvSizeField.Visible = 'off';
            app.PythonEnvSizeField = uieditfield(app.GridLayout3, 'text');
            app.PythonEnvSizeField.Visible = 'off';
            app.PyComputeButton = uibutton(app.GridLayout3, 'push');
            app.PyComputeButton.ButtonPushedFcn = createCallbackFcn(app, @PyComputeButtonPushed, true);
            app.PyComputeButton.Text = 'Compute';
            app.MLComputeButton = uibutton(app.GridLayout3, 'push');
            app.MLComputeButton.ButtonPushedFcn = createCallbackFcn(app, @MLComputeButtonPushed, true);
            app.MLComputeButton.Text = 'Compute';
            app.WarningLabel = uilabel(app.GridLayout3);
            app.WarningLabel.Text = 'WARNING! With a pyenv size of %.2f gb, pyinstaller will inflate compilation size by ~%.f gb!';
            app.Step1DefinedirectoriesPanel = uipanel(app.GridLayout);
            app.Step1DefinedirectoriesPanel.Title = 'Step 1: Define directories';
            app.GridLayout2 = uigridlayout(app.Step1DefinedirectoriesPanel);
            app.SelectFolderButton_NPAL = uibutton(app.GridLayout2, 'push');
            app.SelectFolderButton_NPAL.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_NPALPushed, true);
            app.SelectFolderButton_NPAL.Text = 'Select folder';
            app.NeuroPAL_IDPathLabel = uilabel(app.GridLayout2);
            app.NeuroPAL_IDPathLabel.Text = 'NeuroPAL_ID Path:';
            app.DevtoolsPathLabel = uilabel(app.GridLayout2);
            app.DevtoolsPathLabel.Text = 'Devtools Path:';
            app.SelectFolderButton_Devtools = uibutton(app.GridLayout2, 'push');
            app.SelectFolderButton_Devtools.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_DevtoolsPushed, true);
            app.SelectFolderButton_Devtools.Text = 'Select folder';
            app.Step5CompileNeuroPAL_IDPanel = uipanel(app.GridLayout);
            app.Step5CompileNeuroPAL_IDPanel.Title = 'Step 5: Compile NeuroPAL_ID';
            app.GridLayout10 = uigridlayout(app.Step5CompileNeuroPAL_IDPanel);
            app.Panel_4 = uipanel(app.GridLayout10);
            app.GridLayout12 = uigridlayout(app.Panel_4);
            app.visualize_lightLampLabel = uilabel(app.GridLayout12);
            app.visualize_lightLampLabel.Text = 'visualize_light';
            app.visualize_lightLamp = uilamp(app.GridLayout12);
            app.BinariesLampLabel = uilabel(app.GridLayout12);
            app.BinariesLampLabel.Text = 'Binaries';
            app.BinariesLamp = uilamp(app.GridLayout12);
            app.ModelsLampLabel = uilabel(app.GridLayout12);
            app.ModelsLampLabel.Text = 'Models';
            app.ModelsLamp = uilamp(app.GridLayout12);
            app.NamespacesLampLabel = uilabel(app.GridLayout12);
            app.NamespacesLampLabel.Text = 'Namespaces';
            app.NamespacesLamp = uilamp(app.GridLayout12);
            app.DependenciesLampLabel = uilabel(app.GridLayout12);
            app.DependenciesLampLabel.Text = 'Dependencies';
            app.DependenciesLamp = uilamp(app.GridLayout12);
            app.MatlabRuntimeLamp_2Label = uilabel(app.GridLayout12);
            app.MatlabRuntimeLamp_2Label.Text = 'Matlab Runtime';
            app.MatlabRuntimeLamp_2 = uilamp(app.GridLayout12);
            app.Panel_5 = uipanel(app.GridLayout10);
            app.GridLayout11 = uigridlayout(app.Panel_5);
            app.CompileNeuroPAL_IDButton = uibutton(app.GridLayout11, 'push');
            app.CompileNeuroPAL_IDButton.Enable = 'off';
            app.CompileNeuroPAL_IDButton.Text = 'Compile NeuroPAL_ID';
            app.ForceenablecompilationstartCheckBox = uicheckbox(app.GridLayout11);
            app.ForceenablecompilationstartCheckBox.Text = 'Force enable compilation start';
            app.Step6CodesignNotarizeMacOSonlyPanel = uipanel(app.GridLayout);
            app.Step6CodesignNotarizeMacOSonlyPanel.Title = 'Step 6: Codesign & Notarize (MacOS only)';
            app.GridLayout9 = uigridlayout(app.Step6CodesignNotarizeMacOSonlyPanel);
            app.Button = uibutton(app.GridLayout9, 'push');
            app.Button2 = uibutton(app.GridLayout9, 'push');
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.GridLayout9, 'push');
            app.Button3.Text = 'Button3';
            app.Label = uilabel(app.GridLayout9);
            app.Label2 = uilabel(app.GridLayout9);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.GridLayout9);
            app.Label3.Text = 'Label3';
            app.Button4 = uibutton(app.GridLayout9, 'push');
            app.Button4.Text = 'Button4';
            app.Step7UploadDistributePanel = uipanel(app.GridLayout);
            app.Step7UploadDistributePanel.Title = 'Step 7: Upload & Distribute';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = npal_comp_tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end